classdef robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        XButton                         matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        ProgramArgumentsEditField       matlab.ui.control.EditField
        ProgramArgumentsEditFieldLabel  matlab.ui.control.Label
        ProgramDropDown                 matlab.ui.control.DropDown
        ProgramLabel                    matlab.ui.control.Label
        TorqueSwitch                    matlab.ui.control.Switch
        TorqueSwitchLabel               matlab.ui.control.Label
        GoButton                        matlab.ui.control.Button
        StatusLamp                      matlab.ui.control.Lamp
        DisconnectedLampLabel           matlab.ui.control.Label
        PortDropDown                    matlab.ui.control.DropDown
        PortDropDownLabel               matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        isConnected = false % Track connection state
        launcherInstance;
    end


    methods (Access = private)

        function addPrograms(app)
            % Get the path of the current file
            currentFilePath = which('robot');
            [currentDir, ~, ~] = fileparts(currentFilePath);

            % Construct the path to the 'Programs' folder
            programFolderPath = fullfile(currentDir, 'src\Programs');

            % Load program names
            programFiles = dir(fullfile(programFolderPath, '*.m'));

            programNames = {};
            for i = 1:length(programFiles)
                className = erase(programFiles(i).name, '.m');
                if ~exist(className, 'class')
                    continue; % Skip if the class does not exist
                end
                try
                    % Check if 'name' is a constant property of the class
                    constProps = properties(className);
                    if ismember('name', constProps)
                        propName = 'name';
                        propValue = eval([className '.' propName]);
                        if ~isempty(propValue) && (ischar(propValue) || isstring(propValue))
                            programNames{end+1} = char(propValue);  % Convert to char if it's a string
                        end
                    end
                catch
                    % Handle or ignore errors in class instantiation
                end
            end

            % Ensure programNames is a cell array of character vectors
            programNames = cellstr(programNames);
            app.ProgramDropDown.Items = programNames;

            % After adding programs execute the value change callback to
            % update the arguments field
            app.ProgramDropDownValueChanged;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            screenSize = get(0, 'ScreenSize'); % Get the screen size
            appWidth = screenSize(3) / 3; % App width is 1/3 of the screen width
            appHeight = screenSize(4); % App height is the full screen height

            % Set the position of the app
            app.UIFigure.Position = [screenSize(3) - appWidth, 1, appWidth, appHeight];

            % Set UIFigure to visible
            app.UIFigure.Visible = 'on';

            % Add properly defined programs to the list of programs
            % selectable in the dropdown. (It adds the name property).
            app.addPrograms();

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            selectedPort = app.PortDropDown.Value;
            app.launcherInstance = Launcher.getInstance();

            if ~app.isConnected
                % Connect logic...
                try
                    app.launcherInstance.connect(selectedPort);
                    app.DisconnectedLampLabel.Text = 'Connected';
                    app.StatusLamp.Color = [0, 1, 0]; % Green color
                    app.ConnectButton.Text = 'Disconnect';
                    app.isConnected = true;

                    % Make Panel visible
                    app.Panel.Visible = 'on';

                    % Execute UpdateAndPlotProgram
                    app.launcherInstance.setProgram('UpdateAndPlotProgram');


                catch ME
                    app.DisconnectedLampLabel.Text = ['Error: ', ME.message];
                    app.StatusLamp.Color = [1, 0, 0]; % Red color
                    % Ensure TorqueSwitch is hidden
                    app.TorqueSwitch.Visible = 'off';
                end
            else
                % Disconnect logic...
                app.launcherInstance.disconnect();
                app.DisconnectedLampLabel.Text = 'Disconnected';
                app.StatusLamp.Color = [1, 0, 0]; % Red color
                app.ConnectButton.Text = 'Connect';
                app.isConnected = false;

                % Hide Panel
                app.Panel.Visible = 'off';

                % Close all open figures
                close all;
            end
        end

        % Value changed function: TorqueSwitch
        function TorqueSwitchValueChanged(app, event)
            value = app.TorqueSwitch.Value;

            % Check the value of the switch
            if strcmp(value, 'On')
                % If the switch is 'On', enable the robot's torque
                app.launcherInstance.realRobot.torqueEnable;
                disp('Torque Enabled');
            elseif strcmp(value, 'Off')
                % If the switch is 'Off', disable the robot's torque
                app.launcherInstance.realRobot.torqueDisable;
                disp('Torque Disabled');
            end
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)

            % Check if the robot is connected
            if app.isConnected
                % Disconnect logic
                app.launcherInstance.disconnect();
                app.DisconnectedLampLabel.Text = 'Disconnected';
                app.StatusLamp.Color = [1, 0, 0]; % Red color
                app.ConnectButton.Text = 'Connect';
                app.isConnected = false;

                % Hide Panel
                app.Panel.Visible = 'off';

                % Close all open figures
                close all;
            end

            % Close the app
            delete(app.UIFigure);
        end

        % Value changed function: ProgramDropDown
        function ProgramDropDownValueChanged(app, event)
            selectedProgram = app.ProgramDropDown.Value;

            % Define the visibility and placeholder text based on the selected program
            switch selectedProgram
                case 'Plotting'
                    app.ProgramArgumentsEditFieldLabel.Text = '';
                    app.ProgramArgumentsEditField.Visible = false;
                    app.ProgramArgumentsEditField.Placeholder = '';
                    app.ProgramArgumentsEditField.Value = '';


                case '2D Trajectory'
                    app.ProgramArgumentsEditFieldLabel.Text = '';
                    app.ProgramArgumentsEditField.Visible = false;
                    app.ProgramArgumentsEditField.Placeholder = 'q1, q2, q3, q4';
                    app.ProgramArgumentsEditField.Value = '';


                case 'Set Joints'
                    app.ProgramArgumentsEditField.Visible = true;
                    app.ProgramArgumentsEditFieldLabel.Text = 'Joint Angles [rad]';
                    app.ProgramArgumentsEditField.Placeholder = '';
                    app.ProgramArgumentsEditField.Value = '';


                case 'Set Position'
                    app.ProgramArgumentsEditFieldLabel.Text = 'Endeffector Position [mm]';
                    app.ProgramArgumentsEditField.Visible = true;
                    app.ProgramArgumentsEditField.Placeholder = 'x, y, z';
                    app.ProgramArgumentsEditField.Value = '';


                otherwise
                    app.ProgramArgumentsEditFieldLabel.Text = '';
                    app.ProgramArgumentsEditField.Visible = true;
                    app.ProgramArgumentsEditField.Placeholder = '';
                    app.ProgramArgumentsEditField.Value = '';

            end


        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            % Button pushed function: GoButton
            selectedProgram = app.ProgramDropDown.Value;
            programArgs = strtrim(app.ProgramArgumentsEditField.Value);

            switch selectedProgram
                case 'Plotting'
                    % Run the UpdateAndPlotProgram
                    app.launcherInstance.setProgram('UpdateAndPlotProgram');

                case '2D Trajectory'
                    % Run the FollowTrajectoryProgram
                    app.launcherInstance.setProgram('FollowTrajectoryProgram');

                case 'Set Joints'
                    % Parse the joint angles as a column vector and run the SetQProgram
                    jointAngles = str2num(['[', strrep(programArgs, ',', ';'), ']']);  % Replace   commas with semicolon if any
                    if size(jointAngles, 1) == 4 && size(jointAngles, 2) == 1
                        app.launcherInstance.setProgram('SetQProgram', jointAngles);
                    else
                        uialert(app.UIFigure, 'Please provide 4 joint angles (as a column vector) separated by commas or semicolons.', 'Input Error');
                    end

                case 'Set Position'
                    % Parse the position as a column vector
                    position = str2num(['[', strrep(programArgs, ',', ';'), ']']);  % Replace comma with semicolon if any
                    if size(position, 1) == 3 && size(position, 2) == 1
                        % Assuming you have a program that takes position arguments
                        app.launcherInstance.setProgram('GoToPositionProgram', position);
                    else
                        uialert(app.UIFigure, 'Please provide an endeffector position (as a column vector) in the format: x, y, z', 'Input Error');
                    end

                otherwise
                    uialert(app.UIFigure, 'Please select a valid program.', 'Selection Error');
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Text = 'Port:';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8'};
            app.PortDropDown.Value = 'COM3';
            app.DisconnectedLampLabel = uilabel(app.UIFigure);
            app.DisconnectedLampLabel.Tag = 'Text';
            app.DisconnectedLampLabel.Text = 'Disconnected';
            app.StatusLamp = uilamp(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.GoButton = uibutton(app.Panel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.TorqueSwitchLabel = uilabel(app.Panel);
            app.TorqueSwitchLabel.Text = 'Torque';
            app.TorqueSwitch = uiswitch(app.Panel, 'slider');
            app.TorqueSwitch.ValueChangedFcn = createCallbackFcn(app, @TorqueSwitchValueChanged, true);
            app.TorqueSwitch.Value = 'On';
            app.ProgramLabel = uilabel(app.Panel);
            app.ProgramLabel.Tag = 'ConnectedGroup';
            app.ProgramLabel.Text = 'Program';
            app.ProgramDropDown = uidropdown(app.Panel);
            app.ProgramDropDown.ValueChangedFcn = createCallbackFcn(app, @ProgramDropDownValueChanged, true);
            app.ProgramArgumentsEditFieldLabel = uilabel(app.Panel);
            app.ProgramArgumentsEditFieldLabel.Text = 'Program Arguments';
            app.ProgramArgumentsEditField = uieditfield(app.Panel, 'text');
            app.ProgramArgumentsEditField.Placeholder = 'q1, q2, q3, q4';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end